classdef MeMRI_Processing_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeMRIProcessingToolboxUIFigure  matlab.ui.Figure
        Main                            matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        ImportMenu_3                    matlab.ui.container.Menu
        NoiseDataMenu                   matlab.ui.container.Menu
        MRImagesMenu_2                  matlab.ui.container.Menu
        ProtocolParametersMenu          matlab.ui.container.Menu
        MethodsMenu                     matlab.ui.container.Menu
        ImportMenu                      matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        Parameters                      matlab.ui.container.Menu
        PresetMenu                      matlab.ui.container.Menu
        ImportMenu_2                    matlab.ui.container.Menu
        ExportMenu_2                    matlab.ui.container.Menu
        kSpaceMenu_2                    matlab.ui.container.Menu
        kSpaceMenu                      matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        FIDMenu                         matlab.ui.container.Menu
        FiltersMenu                     matlab.ui.container.Menu
        ApodizationMenu                 matlab.ui.container.Menu
        kSpaceMenu_3                    matlab.ui.container.Menu
        FIDMenu_3                       matlab.ui.container.Menu
        PCADenoisingMenu                matlab.ui.container.Menu
        ZerofillingMenu                 matlab.ui.container.Menu
        CoilCombinationsMenu            matlab.ui.container.Menu
        WSVDMenu                        matlab.ui.container.Menu
        RoemerMenu                      matlab.ui.container.Menu
        ViewerMenu                      matlab.ui.container.Menu
        CSIguiMenu                      matlab.ui.container.Menu
        ViewerMenu_2                    matlab.ui.container.Menu
        About                           matlab.ui.container.Menu
        HelpMenu_2                      matlab.ui.container.Menu
        TutorialMenu                    matlab.ui.container.Menu
        UpdatesMenu                     matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        FileButton                      matlab.ui.control.Button
        FileEditField                   matlab.ui.control.EditField
        BusyLamp                        matlab.ui.control.Lamp
        BusyLampLabel                   matlab.ui.control.Label
        TabGroup_Parameters             matlab.ui.container.TabGroup
        ProcessingTab                   matlab.ui.container.Tab
        tree_methods_kspace_4           matlab.ui.container.CheckBoxTree
        OverwriteNode                   matlab.ui.container.TreeNode
        ExportPresetNode                matlab.ui.container.TreeNode
        WhenfinishedNode                matlab.ui.container.TreeNode
        ShutdownNode                    matlab.ui.container.TreeNode
        OpeninCSIguiNode                matlab.ui.container.TreeNode
        OpendirectoryNode               matlab.ui.container.TreeNode
        Button_Method_Add_3             matlab.ui.control.Button
        Button_Method_Add_2             matlab.ui.control.Button
        ViewButton_2                    matlab.ui.control.Button
        Button_Method_Remove            matlab.ui.control.Button
        Button_Method_Add               matlab.ui.control.Button
        OperationsLabel                 matlab.ui.control.Label
        MethodsLabel                    matlab.ui.control.Label
        ListBox_Methods_Apply           matlab.ui.control.ListBox
        ListBox_Methods_List            matlab.ui.control.ListBox
        ViewButton                      matlab.ui.control.Button
        DefaultButton_2                 matlab.ui.control.Button
        DefaultButton                   matlab.ui.control.Button
        ParametersPresetLabel           matlab.ui.control.Label
        ImportButton_2                  matlab.ui.control.Button
        ExportButton_2                  matlab.ui.control.Button
        ParametersLabel                 matlab.ui.control.Label
        StartProcessingButton           matlab.ui.control.Button
        MethodsPresetLabel              matlab.ui.control.Label
        ImportButton                    matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        ListBox_Log                     matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        % Main memri data struct with all input and parameters.
        filepath = [];
    end
    
    properties (Access = public)
        memri = struct; % Description
    end

    % Callbacks
    methods (Access = private)
        
        % \\ Default callbacks for MeMRI GUI -------------------- %
        % ------------------------------------------------------- %

        function memrigui_reset(app)
            % Clear log listbox 
            app.ListBox_Log.Items = ...
                {'Metabolic MRI Processing Toolbox GUI',''};

            % Reset main filepath
            % app.filepath = [];    

            % Reset memri-data struct in app
            app.memri = struct;
        end

        function app = TabGroup_Parameters_Update(app)
            % Check the "Operations" list and add corresponding tabs
            % to the tab group that allow changing parameters and settings.
            
            % Uses a read-text-file setup
            
            % < This might change soon. implementation ideas >

        end
        
        function memrigui_lamp_toggle(app, state)
            if state
            % Enable busy lamp
            app.BusyLamp.Color = 'g'; app.BusyLamp.Enable = 1;
            else
            % Disable busy lamp
            app.BusyLamp.Color = 'w'; app.BusyLamp.Enable = 0;
            end
            pause(0.0075); 
        end


        % \\ LOG Functions -------------------------------------- %
        % ------------------------------------------------------- %

        function app = memrigui_log(app, varargin)     
        % Writes any strings and most integer lists to log-data. If
        % memri-data is loaded it will write to log-file and display in
        % GUI.

        if isfield(app.memri, 'log')
            % Set log-data in memri-struct;
            app.memri = memri_log(app.memri, varargin{:});

            % Update listbox log display
            memrigui_log_listbox_update(app);  
        else
            
            % First time log-generation
            app.memri.log = app.ListBox_Log.Items;

            % Create log with general MeMRI PTB log-function
            app = memrigui_log(app, 'GUI-Log synced with MeMRI-PTB.');          

        end            
            drawnow; % Update graphics 

        end
        
        function app = memrigui_log_merge(app)
        % Merge log in data and log in GUI during initial parsing of
        % memristruct data.

            % If memri-data available and not executed before.
            if ~isempty(app.memri) && ~app.log_merged
                
                % Log from data
                logData = []; 
                if isfield(app.memri, 'log'), logData = app.memri.log; end

                % Log from GUI
                logGUI = app.ListBox_Log.Items;

                % Initiate logfile
                app = memri_log(app.memri, app.memri.log(:));

                % Merge data
                app.memri.log = cat(2, logData, logGUI);
                app.ListBox_Log.Items = app.memri.log; drawnow;
                
                % Disable this function
                app.log_merged = 1;

                % (Re)set instance number
                app.memri.instance = char(string(datetime('now',...
                    'Format','yyyy.MM.dd.HHmmss')));

                app = memrigui_log(app, 'Merged GUI & data logs.',...
                    ['New log-instance created: ' app.memri.instance]);
            end                
        end

        function memrigui_log_listbox_update(app)            
            if ~isequal(app.ListBox_Log.Items, app.memri.log)
                app.ListBox_Log.Items = flip(app.memri.log, 1);
                app.ListBox_Log.ValueIndex = 1;
                drawnow;
            end                
        end

        % \\ File and Data handling ----------------------------- %
        % ------------------------------------------------------- %
      
        function [fn, fp] = memrigui_file_select(app)
        % Opens file-select UI and set filepath in app.filepath.

            % Select file(s) to load
            [fn, fp] = uigetfile({'*.mat; mat-file'}, ...
                'MeMRI PTB GUI: Select MeMRI data.');    
            app.filepath = [fp fn];
            
        end

        function memrigui_file_read_mat(app)            
            % Load mat-file into app.memri.         
            if ~isempty(app.filepath), app.memri = load(app.filepath); end
        end
        
        function memrigui_file_parse_memri(app)
            % Check if loaded mat-file struct contains memri-field and load
            % into GUI memory.
            
            % Store loaded memri-struct
            if isfield(app.memri,'memri')

                % Save log of GUI
                log = app.memri.log; instance = app.memri.instance;
                
                % Store struct-only
                app.memri = app.memri.memri; % Set appdata.
                
                % Restore log and instance
                app.memri.log = cat(1, app.memri.log, log);
                app.memri.instance = instance;

                % LOG to log-file
                memrigui_log(app, 'MeMRI data-struct loaded into memory.');  
                                 
                % Update GUI
                [~, fn , ext] = fileparts(app.memri.filepath);
                app.FileEditField.Value = [fn ext];
            end
        end
        
        % - Executed to select, load and parse a file.
        function memrigui_file_open(app, doSelect)
        % Select a file via ui, read the data and parse the content.
        % Executed by mb: Main > Open...
            
            if nargin < 2, doSelect = 1; end

            % Select file and log: set app.filepath
            if doSelect, memrigui_file_select(app); end

            % Log & busy-state
            [fp,fn,ext] = fileparts(app.filepath);
            memrigui_log(app,['File: ' fn ext],['Path: ' fp],'Loading data...');
            memrigui_lamp_toggle(app, 1); 

            % Read mat-file
            memrigui_file_read_mat(app);
            
            % Parse memri-struct
            memrigui_file_parse_memri(app);                        
            
            % Show busy state
            memrigui_lamp_toggle(app, 0); 
        end
                
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Method_Add
        function Button_Method_AddPushed(app, event)
            
            %% Add Item

            % Remove empty lines in listbox
            ind_empty = cellfun(@(x) isempty(x), ...
                app.ListBox_Methods_Apply.Items);
            
            % Current list of methods to apply
            methods_apply = app.ListBox_Methods_Apply.Items(~ind_empty);          
            
            % Items to add
            methods_add = app.ListBox_Methods_List.Value;

            % Filter duplicates
            ind_dupes = cellfun(@(x) strcmp(x, methods_add),...
                methods_apply, 'UniformOutput', false);  
            ind_dupes = sum(cell2mat(ind_dupes'),1);
            methods_add(logical(ind_dupes)) =  [];

            % Add entries to lb
            if ~isempty(methods_add)
                app.ListBox_Methods_Apply.Items = cat(2,methods_apply, ...
                    methods_add);
            end

            %% Update Parameters tab window
            TabGroup_Parameters_Update(app);

        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
            % Read the current list of operations and start executing each
            % in order of the list.
            
            % Enable busy lamp
            memrigui_lamp_toggle(app, 1);

            % Get list of operations to apply
            methods_apply = app.ListBox_Methods_Apply.Items;

            % LOG
            memrigui_log(app, 'Applying methods: ', methods_apply{:});
            drawnow;
            for mi = 1:numel(methods_apply)
                switch methods_apply{mi}
                    case 'Average'
                        app.memri = memri_average(app.memri);
                    case 'FFT'
                end

                memrigui_log_listbox_update(app);
            end
                       
            
            % Implement: Read text file with available methods.
            % Allows some things - settings, name and function name

            % // Do some security checks
            % methods_apply
            % for kk = 1:methods_apply
            %     % find Method in list and get its function handle
            %     % use feval to execute memri struct with loaded options
            %     % how to implement variable # options??? Using cell {:}?
            %     % Or icw varargin
            % end
                        
            % Disable busy lamp
            memrigui_lamp_toggle(app, 0);

        end

        % Button pushed function: Button_Method_Remove
        function Button_Method_RemovePushed(app, event)
            % Get list of items to delete from listbox
            del = app.ListBox_Methods_Apply.Value;
            ind_del = cellfun(@(x) strcmp(app.ListBox_Methods_Apply.Items,x),...
                del, 'UniformOutput',false);
            ind_del = sum(cell2mat(ind_del'),1);
            app.ListBox_Methods_Apply.Items(logical(ind_del)) = [];
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            memrigui_file_open(app);
        end

        % Value changed function: FileEditField
        function FileEditFieldValueChanged(app, event)
            
            value = strrep(app.FileEditField.Value, '"', '');
            if isfile(value)
                % Reset GUI
                memrigui_reset(app);

                [~, ~, ext] = fileparts(value);
                if strcmp(ext,'.mat')
                    app.filepath = value; memrigui_file_open(app, 0);
                end
            end                                  
        end

        % Button pushed function: FileButton
        function FileButtonPushed(app, event)
            memrigui_file_open(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeMRIProcessingToolboxUIFigure = uifigure('Visible', 'off');
            app.MeMRIProcessingToolboxUIFigure.Name = 'MeMRI Processing Toolbox';
            app.Main = uimenu(app.MeMRIProcessingToolboxUIFigure);
            app.Main.Text = 'Main';
            app.OpenMenu = uimenu(app.Main);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Tooltip = {'Open a mat-file.'};
            app.OpenMenu.Text = 'Open...';
            app.ImportMenu_3 = uimenu(app.Main);
            app.ImportMenu_3.Text = 'Import';
            app.NoiseDataMenu = uimenu(app.ImportMenu_3);
            app.NoiseDataMenu.Text = 'Noise Data';
            app.MRImagesMenu_2 = uimenu(app.ImportMenu_3);
            app.MRImagesMenu_2.Text = 'MR Images';
            app.ProtocolParametersMenu = uimenu(app.ImportMenu_3);
            app.ProtocolParametersMenu.Text = 'Protocol Parameters';
            app.MethodsMenu = uimenu(app.Main);
            app.MethodsMenu.Text = 'Methods';
            app.ImportMenu = uimenu(app.MethodsMenu);
            app.ImportMenu.Text = 'Import';
            app.ExportMenu = uimenu(app.MethodsMenu);
            app.ExportMenu.Text = 'Export';
            app.ExitMenu = uimenu(app.Main);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.Parameters = uimenu(app.MeMRIProcessingToolboxUIFigure);
            app.Parameters.Text = 'Parameters';
            app.PresetMenu = uimenu(app.Parameters);
            app.PresetMenu.Text = 'Preset...';
            app.ImportMenu_2 = uimenu(app.PresetMenu);
            app.ImportMenu_2.Text = 'Import';
            app.ExportMenu_2 = uimenu(app.PresetMenu);
            app.ExportMenu_2.Text = 'Export';
            app.kSpaceMenu_2 = uimenu(app.Parameters);
            app.kSpaceMenu_2.Text = 'k-Space';
            app.kSpaceMenu = uimenu(app.kSpaceMenu_2);
            app.kSpaceMenu.Text = 'k-Space';
            app.Menu_2 = uimenu(app.kSpaceMenu);
            app.Menu_2.Text = 'Menu';
            app.FIDMenu = uimenu(app.kSpaceMenu_2);
            app.FIDMenu.Text = 'FID';
            app.FiltersMenu = uimenu(app.Parameters);
            app.FiltersMenu.Text = 'Filters';
            app.ApodizationMenu = uimenu(app.FiltersMenu);
            app.ApodizationMenu.Text = 'Apodization';
            app.kSpaceMenu_3 = uimenu(app.ApodizationMenu);
            app.kSpaceMenu_3.Text = 'k-Space';
            app.FIDMenu_3 = uimenu(app.ApodizationMenu);
            app.FIDMenu_3.Text = 'FID';
            app.PCADenoisingMenu = uimenu(app.FiltersMenu);
            app.PCADenoisingMenu.Text = 'PCA Denoising';
            app.ZerofillingMenu = uimenu(app.FiltersMenu);
            app.ZerofillingMenu.Text = 'Zero-filling';
            app.CoilCombinationsMenu = uimenu(app.Parameters);
            app.CoilCombinationsMenu.Text = 'Coil Combinations';
            app.WSVDMenu = uimenu(app.CoilCombinationsMenu);
            app.WSVDMenu.Text = 'WSVD';
            app.RoemerMenu = uimenu(app.CoilCombinationsMenu);
            app.RoemerMenu.Text = 'Roemer';
            app.ViewerMenu = uimenu(app.MeMRIProcessingToolboxUIFigure);
            app.ViewerMenu.Text = 'Viewer';
            app.CSIguiMenu = uimenu(app.ViewerMenu);
            app.CSIguiMenu.Text = 'CSIgui';
            app.ViewerMenu_2 = uimenu(app.ViewerMenu);
            app.ViewerMenu_2.Text = 'Viewer';
            app.About = uimenu(app.MeMRIProcessingToolboxUIFigure);
            app.About.Text = 'About';
            app.HelpMenu_2 = uimenu(app.About);
            app.HelpMenu_2.Text = 'Help';
            app.TutorialMenu = uimenu(app.About);
            app.TutorialMenu.Text = 'Tutorial';
            app.UpdatesMenu = uimenu(app.About);
            app.UpdatesMenu.Text = 'Updates...';
            app.AboutMenu = uimenu(app.About);
            app.AboutMenu.Separator = 'on';
            app.AboutMenu.Text = 'About';
            app.ListBox_Log = uilistbox(app.MeMRIProcessingToolboxUIFigure);
            app.ListBox_Log.Items = {'Metabolic MRI Processing Toolbox GUI', ''};
            app.ListBox_Log.Value = 'Metabolic MRI Processing Toolbox GUI';
            app.ExportButton = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.ExportButton.Text = 'Export';
            app.ImportButton = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.ImportButton.Text = 'Import';
            app.MethodsPresetLabel = uilabel(app.MeMRIProcessingToolboxUIFigure);
            app.MethodsPresetLabel.Text = 'Methods Preset:';
            app.StartProcessingButton = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Text = 'Start Processing';
            app.ParametersLabel = uilabel(app.MeMRIProcessingToolboxUIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.ExportButton_2 = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.ExportButton_2.Text = 'Export';
            app.ImportButton_2 = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.ImportButton_2.Text = 'Import';
            app.ParametersPresetLabel = uilabel(app.MeMRIProcessingToolboxUIFigure);
            app.ParametersPresetLabel.Text = 'Parameters Preset:';
            app.DefaultButton = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.DefaultButton.Text = 'Default';
            app.DefaultButton_2 = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.DefaultButton_2.Text = 'Default';
            app.ViewButton = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.ViewButton.Text = 'View';
            app.ListBox_Methods_List = uilistbox(app.MeMRIProcessingToolboxUIFigure);
            app.ListBox_Methods_List.Items = {'Average', 'Filter Correction', 'FFT (k-space)', 'FFT', 'PCA Denoising', 'WSVD', 'Roemer', 'Merge MRI', 'Apodization', 'Zero-filling', 'iFFT (k-space)', 'iFFT'};
            app.ListBox_Methods_List.Multiselect = 'on';
            app.ListBox_Methods_List.Value = {'Filter Correction'};
            app.ListBox_Methods_Apply = uilistbox(app.MeMRIProcessingToolboxUIFigure);
            app.ListBox_Methods_Apply.Items = {''};
            app.ListBox_Methods_Apply.Multiselect = 'on';
            app.ListBox_Methods_Apply.Value = {''};
            app.MethodsLabel = uilabel(app.MeMRIProcessingToolboxUIFigure);
            app.MethodsLabel.Text = 'Methods:';
            app.OperationsLabel = uilabel(app.MeMRIProcessingToolboxUIFigure);
            app.OperationsLabel.Text = 'Operations:';
            app.Button_Method_Add = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_Method_Add.ButtonPushedFcn = createCallbackFcn(app, @Button_Method_AddPushed, true);
            app.Button_Method_Add.Tooltip = {'Add method(s) as a processing step(s) to the "Operations" list.'};
            app.Button_Method_Add.Text = '→';
            app.Button_Method_Remove = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_Method_Remove.ButtonPushedFcn = createCallbackFcn(app, @Button_Method_RemovePushed, true);
            app.Button_Method_Remove.Tooltip = {'Remove selected processing method(s) in "Operations" list. '};
            app.Button_Method_Remove.Text = 'X';
            app.ViewButton_2 = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.ViewButton_2.Text = 'View';
            app.Button_Method_Add_2 = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_Method_Add_2.Text = '↑';
            app.Button_Method_Add_3 = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.Button_Method_Add_3.Text = '↓';
            app.TabGroup_Parameters = uitabgroup(app.MeMRIProcessingToolboxUIFigure);
            app.ProcessingTab = uitab(app.TabGroup_Parameters);
            app.ProcessingTab.Title = 'Processing';
            app.tree_methods_kspace_4 = uitree(app.ProcessingTab, 'checkbox');
            app.OverwriteNode = uitreenode(app.tree_methods_kspace_4);
            app.OverwriteNode.Text = 'Overwrite';
            app.ExportPresetNode = uitreenode(app.tree_methods_kspace_4);
            app.ExportPresetNode.Text = 'Export Preset';
            app.WhenfinishedNode = uitreenode(app.tree_methods_kspace_4);
            app.WhenfinishedNode.Text = 'When finished...';
            app.ShutdownNode = uitreenode(app.WhenfinishedNode);
            app.ShutdownNode.Text = 'Shutdown';
            app.OpeninCSIguiNode = uitreenode(app.WhenfinishedNode);
            app.OpeninCSIguiNode.Text = 'Open in CSIgui';
            app.OpendirectoryNode = uitreenode(app.WhenfinishedNode);
            app.OpendirectoryNode.Text = 'Open directory';
            % Assign Checked Nodes
            app.tree_methods_kspace_4.CheckedNodes = [app.OpeninCSIguiNode];
            app.BusyLampLabel = uilabel(app.MeMRIProcessingToolboxUIFigure);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.MeMRIProcessingToolboxUIFigure);
            app.BusyLamp.Enable = 'off';
            app.FileEditField = uieditfield(app.MeMRIProcessingToolboxUIFigure, 'text');
            app.FileEditField.ValueChangedFcn = createCallbackFcn(app, @FileEditFieldValueChanged, true);
            app.FileEditField.Tooltip = {'Enter the full filepath to load a file. (Or use the main menu or menubar.)'};
            app.FileEditField.Value = 'D:\OneDrive\Matlab\Metabolic-MRI-Processing-Toolbox\_ExampleData\CSI_Philips\raw_019.mat';
            app.FileButton = uibutton(app.MeMRIProcessingToolboxUIFigure, 'push');
            app.FileButton.ButtonPushedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.FileButton.Tooltip = {'Open a mat-file.'};
            app.FileButton.Text = 'File:';
            % Show the figure after all components are created
            app.MeMRIProcessingToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MeMRI_Processing_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeMRIProcessingToolboxUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeMRIProcessingToolboxUIFigure)
        end
    end
end